classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Label                  matlab.ui.control.Label            % Analiza...
        Label2                 matlab.ui.control.Label            % Selecte...
        Panel                  matlab.ui.container.Panel          % Frecven...
        Panel2                 matlab.ui.container.Panel          % Dimensi...
        Button                 matlab.ui.control.Button           % Inregis...
        TabGroup               matlab.ui.container.TabGroup       % Analiza...
        Tab                    matlab.ui.container.Tab            % Analiza...
        UIAxes                 matlab.ui.control.UIAxes           % Amplitu...
        UIAxes2                matlab.ui.control.UIAxes           % Putere ...
        Tab2                   matlab.ui.container.Tab            % Comparatie
        Button2                matlab.ui.control.Button           % Exit
        Button3                matlab.ui.control.Button           % Reda in...
        LabelDropDown2         matlab.ui.control.Label            % Limba
        DropDown2              matlab.ui.control.DropDown         % Romana,...
        LabelDropDown          matlab.ui.control.Label            % Serie r...
        DropDown               matlab.ui.control.DropDown         % , 1, 2, 3
        LabelEditField         matlab.ui.control.Label            % Rotatio...
        EditField              matlab.ui.control.EditField       
        LabelEditField2        matlab.ui.control.Label            % Orbital...
        EditField2             matlab.ui.control.EditField       
        LabelNumericEditField  matlab.ui.control.Label            % Diamete...
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % Contact...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % PCD of ...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
    end

    
    properties (Access = public)
        file % loaded audio file
        selectedBearing
        bearings
        fc
        fb
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.bearings = loadBearings();
        end

        % Button button pushed function
        function loadAudioFile(app)
            file = loadFile();
            app.file = strcat('uploaded audio/', file);
        end

        % Button3 button pushed function
        function playAudio(app)
            [y, Fs] = audioread(app.file);
            % sound(y, Fs);
            N = length(y(:,1));
            Y = fft(y(:,1));
            plot_S(1/Fs, Y, app.UIAxes);
            plot_S(1/Fs, (abs(Y).^2)/N, app.UIAxes2);
        end

        % DropDown value changed function
        function selectBearing(app, event)
            app.selectedBearing = str2num(app.DropDown.Value);
            getRotationFrequency(app);
            getOrbitalFrequency(app);
            getDiameter(app);
            getPCD(app);
            getAngle(app);
                       
        end

        % TextArea value changed function
        function getFrequency(app)
            
            
        end

        % EditField value changed function
        function getRotationFrequency(app)
            value = app.EditField.Value;
            app.fb = app.bearings(app.selectedBearing).getRotationFrequency();
            app.EditField.Value = num2str(app.fb);
            
        end

        % EditField2 value changed function
        function getOrbitalFrequency(app)
            value = app.EditField2.Value;
            app.fc = app.bearings(app.selectedBearing).getOrbitalFrequency();
            app.EditField2.Value = num2str(app.fc);
        end

        % NumericEditField value changed function
        function getDiameter(app)
            value = app.NumericEditField.Value;
            Da = app.bearings(app.selectedBearing).getDiameter();
            app.NumericEditField.Value = Da;
            
        end

        % NumericEditField4 value changed function
        function getPCD(app)
            value = app.NumericEditField4.Value;
            PCD = app.bearings(app.selectedBearing).getPCD();
            app.NumericEditField4.Value = PCD;
        end

        % NumericEditField3 value changed function
        function getAngle(app)
            value = app.NumericEditField3.Value;
            angle = app.bearings(app.selectedBearing).getAngle();
            app.NumericEditField3.Value = angle;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Analiza spectrala a lagarelor cu rulmenti';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Selecteaza rulmentul din biblioteca';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Frecventele rulmentului selectat';
            app.Panel.Units = 'pixels';
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Rotation Frequency';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @getRotationFrequency);
            app.LabelEditField2 = uilabel(app.Panel);
            app.LabelEditField2.Text = 'Orbital Frequency';
            app.EditField2 = uieditfield(app.Panel, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @getOrbitalFrequency);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Dimensiunile rulmentului selectat';
            app.Panel2.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel2);
            app.LabelNumericEditField.Text = 'Diameter of rolling elements';
            app.NumericEditField = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @getDiameter);
            app.LabelNumericEditField3 = uilabel(app.Panel2);
            app.LabelNumericEditField3.Text = 'Contact angle(rad)';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @getAngle);
            app.LabelNumericEditField4 = uilabel(app.Panel2);
            app.LabelNumericEditField4.Text = 'PCD of rolling elements';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @getPCD);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @loadAudioFile);
            app.Button.Text = 'Inregistreaza zgomot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Analiza spectrala';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Amplitudine frecventa');
            xlabel(app.UIAxes, 'frecventa');
            ylabel(app.UIAxes, 'amplitudine');
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Putere frecventa');
            xlabel(app.UIAxes2, 'frecventa');
            ylabel(app.UIAxes2, 'putere');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Comparatie';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Exit';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @playAudio);
            app.Button3.Text = 'Reda inregistrarea';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Limba';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'Romana', 'English', 'French'};
            app.DropDown2.Value = 'Romana';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Serie rulment';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'', '1', '2', '3'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @selectBearing, true);
            app.DropDown.Value = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end